classdef ESCWaveformGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ESCWaveGen                      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SignalSources                   matlab.ui.container.Tab
        SelectDataFolderRadar           matlab.ui.control.Button
        LabelListBox                    matlab.ui.control.Label
        SelectRadarFile                 matlab.ui.control.ListBox
        SelectDataFolderLTE             matlab.ui.control.Button
        LTEDirLbl                       matlab.ui.control.Label
        RadarDirLbl                     matlab.ui.control.Label
        LTELMessageLbl                  matlab.ui.control.Label
        RadarMetaFileLbl                matlab.ui.control.Label
        SelectRadarMetaDataFile         matlab.ui.control.Button
        LTEWaveformDurationSecLabel     matlab.ui.control.Label
        LTEWaveformDuration             matlab.ui.control.NumericEditField
        SetRadarGainManually            matlab.ui.control.CheckBox
        RadarGainEdt                    matlab.ui.control.NumericEditField
        WaveformPreview                 matlab.ui.container.Tab
        SignalTiming                    matlab.ui.container.Panel
        ForwardonfileLabel              matlab.ui.control.Label
        StartOnFile                     matlab.ui.control.NumericEditField
        TotalpreviewLabel               matlab.ui.control.Label
        TotalPreviewTime                matlab.ui.control.NumericEditField
        SegmentPreviewTimeLabel         matlab.ui.control.Label
        SegmentPreviewTime              matlab.ui.control.NumericEditField
        PanelLTE2                       matlab.ui.container.Panel
        LED_LTE2                        matlab.ui.control.Lamp
        SliderFreqOffsetLTE2            matlab.ui.control.Slider
        Label13                         matlab.ui.control.Label
        Label15                         matlab.ui.control.Label
        SwitchLTE2                      matlab.ui.control.ToggleSwitch
        LTE2FreqNum                     matlab.ui.control.NumericEditField
        Label14                         matlab.ui.control.Label
        KnobGainLTE2                    matlab.ui.control.Knob
        GainLTE2Num                     matlab.ui.control.NumericEditField
        ChannelTypeLTE1_2Label          matlab.ui.control.Label
        ChannelTypeLTE2                 matlab.ui.control.DropDown
        PanelLTE1                       matlab.ui.container.Panel
        LED_LTE1                        matlab.ui.control.Lamp
        SliderFreqOffsetLTE1            matlab.ui.control.Slider
        Label17                         matlab.ui.control.Label
        Label18                         matlab.ui.control.Label
        KnobGainLTE1                    matlab.ui.control.Knob
        Label19                         matlab.ui.control.Label
        SwitchLTE1                      matlab.ui.control.ToggleSwitch
        LTE1FreqNum                     matlab.ui.control.NumericEditField
        GainLTE1Num                     matlab.ui.control.NumericEditField
        ChannelTypeDropDownLabel        matlab.ui.control.Label
        ChannelTypeLTE1                 matlab.ui.control.DropDown
        PanelRadar                      matlab.ui.container.Panel
        RadarLED                        matlab.ui.control.Lamp
        SliderFreqOffsetRadar           matlab.ui.control.Slider
        Label21                         matlab.ui.control.Label
        Label22                         matlab.ui.control.Label
        KnobGainRadar                   matlab.ui.control.Knob
        SwitchRadar                     matlab.ui.control.ToggleSwitch
        Label23                         matlab.ui.control.Label
        RadarFreqNum                    matlab.ui.control.NumericEditField
        GainRadarNum                    matlab.ui.control.NumericEditField
        Waveforms                       matlab.ui.container.Panel
        Stop                            matlab.ui.control.Button
        Preview                         matlab.ui.control.Button
        DisplayPreview                  matlab.ui.container.ButtonGroup
        Frequency                       matlab.ui.control.RadioButton
        Time                            matlab.ui.control.RadioButton
        Spectrogram                     matlab.ui.control.RadioButton
        ReadyStateLbl                   matlab.ui.control.Label
        SIRLbl                          matlab.ui.control.Label
        ShowSIR                         matlab.ui.control.CheckBox
        LTEChannelState                 matlab.ui.control.CheckBox
        AWGN                            matlab.ui.control.CheckBox
        AWGNVar                         matlab.ui.control.Slider
        AWGNVarNum                      matlab.ui.control.NumericEditField
        LoadDefaults                    matlab.ui.control.Button
        WaveformGenerationTab           matlab.ui.container.Tab
        SingleFileGenerationPanel       matlab.ui.container.Panel
        SaveWaveformAs                  matlab.ui.control.Button
        SingleFileGenIndlbl             matlab.ui.control.Label
        SaveWavefomAsedt                matlab.ui.control.EditField
        GenerateThisWaveform            matlab.ui.control.Button
        MultipleFileGenerationPanel     matlab.ui.container.Panel
        SaveWaveformDir                 matlab.ui.control.Button
        GenerateWaveforms               matlab.ui.control.Button
        SaveWavefomsedt                 matlab.ui.control.EditField
        ParallelProcess                 matlab.ui.control.CheckBox
        SetNumberofWorkersLabel         matlab.ui.control.Label
        SetNumberofWorkers              matlab.ui.control.Spinner
        AvailableNumberofWorkers        matlab.ui.control.Label
        MultiFileGenIndlbl              matlab.ui.control.Label
        FileNamePrefixEditFieldLabel    matlab.ui.control.Label
        FileNamePrefixedt               matlab.ui.control.EditField
        NumberofFilesSpinnerLabel       matlab.ui.control.Label
        NumberofFilesSpinner            matlab.ui.control.Spinner
        ParametersPanel                 matlab.ui.container.Panel
        SingleFileGenerationTable1      matlab.ui.control.Table
        SingleFileGenerationTable2      matlab.ui.control.Table
        SaveSignalInfo                  matlab.ui.control.CheckBox
        LTEChannelTypeTbl               matlab.ui.control.Table
        WaveformPlayerTab               matlab.ui.container.Tab
        DisplayPlayer                   matlab.ui.container.ButtonGroup
        FrequencyPlayer                 matlab.ui.control.RadioButton
        TimePlayer                      matlab.ui.control.RadioButton
        SpectrogramButton               matlab.ui.control.RadioButton
        StopPlayer                      matlab.ui.control.Button
        PlayPlayer                      matlab.ui.control.Button
        SelectWaveformFolderPlayer      matlab.ui.control.Button
        SelectRadarFile_2Label          matlab.ui.control.Label
        SelectWaveformFilePlayer        matlab.ui.control.ListBox
        WaveformInfoLabel               matlab.ui.control.Label
        WaveformInfo                    matlab.ui.control.TextArea
        SetdataScaleGainEditFieldLabel  matlab.ui.control.Label
        SetdataScaleGainEdt             matlab.ui.control.NumericEditField
        DebugLbl                        matlab.ui.control.Label
        LoadPreviewParameters           matlab.ui.control.Button
        ResetSpectrumAnalyzerandTimeSope  matlab.ui.control.Button
        LogoImage                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        currDir; % Description
        txWaveform_resampled;
        Fs;
        hSpecAn;
        hTScope; 
        hSpectr;
        FsLTE=15.36e6;
        RadarInfo;
        fileNo=1;
        Radar_folder_name;
        StopSimState=0;
        DisplayPlayState=1; % Frequency=1;Time=2; Spectrogram 3
        DisplayPreviewState=1; % Frequency=1;Time=2; Spectrogram 3
        StopPlayState=0;
        
        WaveformSaveflag=0;
        SaveWaveformFilePath;
        RAInfo;
        BandPower;
        %% Defaults
        Parameters;
        RadarWaveformsPaths
        folderBrowser=folderBrowserGUI% Description
        playWaveform
        generateWaveform
        LTEChNum=2
        %SourceRoot
        appDir
        configDir
        resDir
        dspDir
        playerSamplesPerSegment=1024*32;
        ThreeGPPChTypes
        parState
    end
    
    
    methods (Access = private)


        function logicR=OnOff2logicfunc(app,OnOff)
            switch OnOff
                case 'On'
                    logicR=true;
                case 'Off'
                    logicR=false;
            end
        end
        
        function OnOffR=logic2OnOfffunc(app,logicIn)
            switch logicIn
                case true
                    OnOffR='On';
                case false
                    OnOffR='Off';
            end
        end
        
        function EnableR=logic2Enablefunc(app,logicIn)
            switch logicIn
                case true
                    EnableR='on';
                case false
                    EnableR='off';
            end
        end
        
        function durationCh=sec2DurationChar(app,tsec)
            displayFormat='hh:mm:ss.SSS';
            runtime=duration(0,0,tsec,'Format',displayFormat);
            durationCh=sprintf('%s',runtime);
        end
        
        %function cdIfNotDepComp(app,cdDir)
        %   if ~(ismcc || isdeployed)
        %        cd(cdDir);
        %    end
        %    
        %end
        
        function loadDefaults(app)
            %ConfigFileName='ESCWGConfigurationEmptyDir.json';
            ConfigFileName='ESCWGConfiguration.json';
            ConfigFile=fullfile(app.configDir,ConfigFileName);
            try
                ConfigText =fileread(ConfigFile);
                ParStruct=jsondecode(ConfigText);
            catch msg
                %assignin('base','msg',msg);
                uialert(app.ESCWaveGen,msg.message,'Error: Please close program and address the issue');
            end
            app.Parameters=ParStruct.Parameters;
            %assignin('base','Parameters',  app.Parameters);
            app.Fs=app.Parameters.Basic.Fs*1e6;
            app.generateWaveform=waveforms(app.Fs);
            
            
            app.generateWaveform=setUpLTECh(app.generateWaveform,struct2cell(app.Parameters.Signal.LTEChannelType)',app.LTEChNum);
            app.ThreeGPPChTypes=get3GPPInfo(app.generateWaveform);
            app.ChannelTypeLTE1.Items=app.ThreeGPPChTypes;
            app.ChannelTypeLTE2.Items=app.ThreeGPPChTypes;
            %to do need to add selected from default
            app.ChannelTypeLTE1.Value=app.Parameters.Signal.LTEChannelType.LTE1;
            app.ChannelTypeLTE2.Value=app.Parameters.Signal.LTEChannelType.LTE2;
            
            app.RadarGainEdt.Enable='off';
            app.RadarGainEdt.Value=1e-7;

            app.LTEWaveformDuration.Value=app.Parameters.Basic.LTEWaveformDuration;
            app.LTEWaveformDuration.Limits=[0.0 app.Parameters.Basic.LTEWaveformMaxDuration];
            %% Initialize Freq comp
            app.RadarFreqNum.Value=app.Parameters.FrequencyOffsetMHz.Radar;
            app.SliderFreqOffsetRadar.Value=app.Parameters.FrequencyOffsetMHz.Radar;
            app.LTE1FreqNum.Value=app.Parameters.FrequencyOffsetMHz.LTE1;
            app.SliderFreqOffsetLTE1.Value=app.Parameters.FrequencyOffsetMHz.LTE1;
            app.LTE2FreqNum.Value=app.Parameters.FrequencyOffsetMHz.LTE2;
            app.SliderFreqOffsetLTE2.Value=app.Parameters.FrequencyOffsetMHz.LTE2;
            %% Inlitlialize Gain
            app.GainRadarNum.Value=app.Parameters.Gain.Radar;
            app.KnobGainRadar.Value=app.Parameters.Gain.Radar;
            app.GainLTE1Num.Value=app.Parameters.Gain.LTE1;
            app.KnobGainLTE1.Value=app.Parameters.Gain.LTE1;
            app.GainLTE2Num.Value=app.Parameters.Gain.LTE2;
            app.KnobGainLTE2.Value=app.Parameters.Gain.LTE2;
            
            app.AWGNVar.Value=app.Parameters.Signal.AWGNVariance;
            app.AWGNVarNum.Value=app.Parameters.Signal.AWGNVariance;
            %% Initiate Switches and Status LEDs
            app.SwitchRadar.Items={'',''};
            app.SwitchRadar.ItemsData={'Off','On'};
            app.SwitchLTE1.Items={'',''};
            app.SwitchLTE1.ItemsData={'Off','On'};
            app.SwitchLTE2.Items={'',''};
            app.SwitchLTE2.ItemsData={'Off','On'};
            
            app.SwitchRadar.Value=logic2OnOfffunc(app,app.Parameters.SignalStatus.Radar);
            app.SwitchLTE1.Value=logic2OnOfffunc(app,app.Parameters.SignalStatus.LTE1);
            app.SwitchLTE2.Value=logic2OnOfffunc(app,app.Parameters.SignalStatus.LTE2);
            app.RadarLED.Enable = logic2OnOfffunc(app,app.Parameters.SignalStatus.Radar);
            app.LED_LTE1.Enable = logic2OnOfffunc(app,app.Parameters.SignalStatus.LTE1);
            app.LED_LTE2.Enable = logic2OnOfffunc(app,app.Parameters.SignalStatus.LTE2);
            
            app.LTEChannelState.Value=app.Parameters.Signal.LTEChannelStatus;
            app.ChannelTypeLTE1.Enable=logic2Enablefunc(app,app.Parameters.Signal.LTEChannelStatus);
            app.ChannelTypeLTE2.Enable=logic2Enablefunc(app,app.Parameters.Signal.LTEChannelStatus);
            
            app.AWGN.Value=app.Parameters.Signal.AWGNStatus;
            %% Initialize Preview Parameters
            app.TotalPreviewTime.Value=app.Parameters.Basic.TotalPreviewTime;
            app.SegmentPreviewTime.Value=app.Parameters.Basic.SegmentPreviewTime;
            app.StartOnFile.Value=app.Parameters.Basic.StartOnFile;
            
            %% Initialize gen setting
            app.SaveSignalInfo.Value=app.Parameters.Basic.SaveSignalInfo;
            app.ParallelProcess.Value=false;
            app.SetNumberofWorkers.Enable='on';
            poolobj = gcp('nocreate'); % If no pool, do not create new one.
            if ~isempty(poolobj)
                app.AvailableNumberofWorkers.Text=['Available Number of Workers=',num2str(poolobj.NumWorkers)];
            end
            %% Initialize components
            if isempty(app.txWaveform_resampled')
                app.LTELMessageLbl.Text=sprintf('No LTE Data loaded');
            end
            %% Read Measurement Parameters
            %{
            MetaRadar_info_full_path=fullfile(app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_path,...
                app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_name);
            if (exist(MetaRadar_info_full_path, 'file') == 2)
                app.SelectRadarMetaDataFile.Enable='off';
                
                %app.RadarInfo=read_xsl_info_f(MetaRadar_info_full_path);
                app.generateWaveform=setRadarMetaFile(app.generateWaveform,MetaRadar_info_full_path);
                app.generateWaveform=readRadarMeta(app.generateWaveform);
                app.RadarMetaFileLbl.Text=MetaRadar_info_full_path;
                
            end 
            %}
            %% Read Measurement Parameters
            MetaRadar_info_full_path=fullfile(app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_path,...
                app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_name);
            if (exist(MetaRadar_info_full_path, 'file') == 2)
                app.SelectRadarMetaDataFile.Enable='off';
                
                %app.RadarInfo=read_xsl_info_f(MetaRadar_info_full_path);
                app.generateWaveform=setRadarMetaFile(app.generateWaveform,MetaRadar_info_full_path);
                app.generateWaveform=readRadarMeta(app.generateWaveform);
                app.RadarMetaFileLbl.Text=MetaRadar_info_full_path;
            elseif (exist(app.RadarMetaFileLbl.Text, 'file') == 2)
                app.SelectRadarMetaDataFile.Enable='off';
                app.generateWaveform=setRadarMetaFile(app.generateWaveform,app.RadarMetaFileLbl.Text);
                app.generateWaveform=readRadarMeta(app.generateWaveform);
            else
                app.SelectRadarMetaDataFile.Enable='on';
            end
            
        end
        
        
        function loadSpectrumAnalyzer(app)
            %% Initialize Spectrum Analyzer
            fldnms=fieldnames(app.Parameters.SpectrumAnalyzer);
            for fldnm_Ind=1:length(fldnms)
                if ~isrow(app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind}))&& isnumeric(app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind}))
                    app.hSpecAn.(fldnms{fldnm_Ind}) = app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind}).';
                else
                    app.hSpecAn.(fldnms{fldnm_Ind}) = app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind});
                end
            end
            
        end
        
        function loadTimeScope(app)
            %% Initialize Time Scope
            fldnms=fieldnames(app.Parameters.TimeScope);
            for fldnm_Ind=1:length(fldnms)
                if ~isrow(app.Parameters.TimeScope.(fldnms{fldnm_Ind}))&& isnumeric(app.Parameters.TimeScope.(fldnms{fldnm_Ind}))
                    app.hTScope.(fldnms{fldnm_Ind}) = app.Parameters.TimeScope.(fldnms{fldnm_Ind}).';
                else
                    app.hTScope.(fldnms{fldnm_Ind}) = app.Parameters.TimeScope.(fldnms{fldnm_Ind});
                end
            end

        end
        
        function loadSpectrogram(app)
            fldnms=fieldnames(app.Parameters.Spectrogram);
            for fldnm_Ind=1:length(fldnms)
                if ~isrow(app.Parameters.Spectrogram.(fldnms{fldnm_Ind}))&& isnumeric(app.Parameters.Spectrogram.(fldnms{fldnm_Ind}))
                    app.hSpectr.(fldnms{fldnm_Ind}) = app.Parameters.Spectrogram.(fldnms{fldnm_Ind}).';
                else
                    app.hSpectr.(fldnms{fldnm_Ind}) = app.Parameters.Spectrogram.(fldnms{fldnm_Ind});
                end
            end
        end  
        
        function setUpWaveformPlayer(app)
            
            app.playWaveform=setMeasFile(app.playWaveform,app.SelectWaveformFilePlayer.Value);
            app.playWaveform=initFile(app.playWaveform);
            seekPositionSamples=0;
            app.playWaveform=setSeekPositionSamples(app.playWaveform,seekPositionSamples);
            
            if exist([app.SelectWaveformFilePlayer.Value(1:end-3),'json'],'file')==2
                txtSignal=fileread([app.SelectWaveformFilePlayer.Value(1:end-3),'json']);
                % Simple json format
                txtSignal = regexprep(txtSignal,{'{','}',','},{'{\n','\n}',',\n'});
                app.WaveformInfo.Value=txtSignal;
                app.WaveformInfo.Visible='on';
                %curRtLtIndx=regexp(txtSignal, {'{','}'})
            else
                FileInfo=dir(app.SelectWaveformFilePlayer.Value);
                timeSecPerFile=(1/app.Fs)*FileInfo.bytes/4;
                app.WaveformInfo.Value=['Total process time in sec=',num2str(timeSecPerFile)];
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.currDir=pwd;
            %% Defaults
            app.appDir='..\app';
            %app.utilDir='..\util';
            app.dspDir='..\dsp';
            app.configDir='..\config';
            app.resDir='..\res';
            if ~(ismcc || isdeployed)
                appPath = mfilename('fullpath');
                [progDir,~,~] = fileparts(appPath);
                cd(progDir);
                addpath(app.appDir);
                %addpath(app.utilDir);
                addpath(app.dspDir);
                addpath(app.configDir);
                addpath(app.resDir);
            end
            
            loadDefaults(app);
            app.hSpecAn = dsp.SpectrumAnalyzer;
            app.hSpecAn.SampleRate =app.Fs;
            app.hTScope= dsp.TimeScope;
            app.hTScope.SampleRate=app.Fs;
            app.hSpectr= dsp.SpectrumAnalyzer;
            app.hSpectr.SampleRate =app.Fs;
            
            loadSpectrumAnalyzer(app);
            loadTimeScope(app);
            loadSpectrogram(app);
            
            imDat = imread(fullfile(app.resDir,'CTL_logo.png'),'png');
            set(app.LogoImage,'Box','off','XGrid','off', 'YGrid','off','XTick',[],'YTick',[]);
            app.LogoImage.XAxis.Visible='off';
            app.LogoImage.YAxis.Visible='off';
            
            %set(app.LogoImage,'Axis', 'off');
            %imagesc(app.LogoImage,C);
            imshow(imDat,'Parent',app.LogoImage);
        end

        % Button pushed function: SelectDataFolderRadar
        function SelectDataFolderRadarButtonPushed(app, event)
            dialogText='Select Radar waveforms folder';
            defaultExt='dat';
            app.folderBrowser=setDefaultDir(app.folderBrowser,app.Parameters.Basic.DefaultDirectories.RadarDir);
            app.folderBrowser=setDialogText(app.folderBrowser,dialogText);
            app.folderBrowser=setDefaultExt(app.folderBrowser,defaultExt);
            app.folderBrowser=SelectFolder(app.folderBrowser,app.ESCWaveGen);
            fileNames=getFileNames(app.folderBrowser,'withext');
            assignin('base','fileNames',fileNames);
            if ~isempty(fileNames)
                app.SelectRadarFile.Items=fileNames;
                app.RadarWaveformsPaths=getFullFileNamesWithPath(app.folderBrowser,'withext');
                app.SelectRadarFile.ItemsData=1:length(fileNames);
                %assignin('base','file_paths',app.RadarWaveformsPaths)
                app.SelectRadarFile.Value=app.fileNo;
            end
            
            %clear fileNames;
        end

        % Value changed function: SwitchRadar
        function SwitchRadarValueChanged(app, event)
            
            app.RadarLED.Enable = app.SwitchRadar.Value;
            app.Parameters.SignalStatus.Radar=OnOff2logicfunc(app,app.SwitchRadar.Value);
            %logicR=OnOff2logicfunc(app,app.SwitchRadar.Value);
        end

        % Button pushed function: SelectDataFolderLTE
        function SelectDataFolderLTEButtonPushed(app, event)
            dialogText='Select LTE waveforms folder';
            defaultExt='mat';
            app.folderBrowser=setDefaultDir(app.folderBrowser,app.Parameters.Basic.DefaultDirectories.LTEDir);
            app.folderBrowser=setDialogText(app.folderBrowser,dialogText);
            app.folderBrowser=setDefaultExt(app.folderBrowser,defaultExt);
            app.folderBrowser=SelectFolder(app.folderBrowser,app.ESCWaveGen);
            fileNames=getFileNames(app.folderBrowser,'withext');
            if ~isempty(fileNames)
                LTEWaveformsPaths=getFullFileNamesWithPath(app.folderBrowser,'withext');
                
                
                [p,q] = rat(app.Fs / app.FsLTE);
                txwavelong=[];
                app.LTEDirLbl.Text=['LTE Data Dir:',app.Parameters.Basic.DefaultDirectories.LTEDir];
                app.LTELMessageLbl.Text=sprintf('Loading LTE data...\n this may take a few minutes');
                tdLTE=tic;
                for f_in=1:5%length(LTEWaveformsPaths) % comment for speed use for f_in=1:2
                    %for f_in=1:2
                    WaveFile(f_in)=load(char(LTEWaveformsPaths(f_in)));
                    txwavelong=[txwavelong;WaveFile(f_in).txWaveform];
                    
                end
                app.txWaveform_resampled = resample(txwavelong,p,q);
                tdLTE=toc(tdLTE);
                app.LTELMessageLbl.Text=sprintf('Done loading LTE after %d seconds',tdLTE);
                
            end           
            
        end

        % Value changed function: SelectRadarFile
        function SelectRadarFileValueChanged(app, event)
            app.fileNo = app.SelectRadarFile.Value;
        end

        % Button pushed function: Preview
        function PreviewButtonPushed(app, event)
             
            switch app.DisplayPreviewState
                case 1
                    app.hSpecAn.show;
                    app.hTScope.hide;
                    app.hSpectr.hide;
                case 2
                    app.hSpecAn.hide;
                    app.hTScope.show;
                    app.hSpectr.hide;
                case 3
                    app.hSpecAn.hide;
                    app.hTScope.hide;
                    app.hSpectr.show;
            end
            
            if ~isempty(app.SelectRadarFile.Items)
                file_path_name=char(app.RadarWaveformsPaths(app.fileNo));
            else
                file_path_name=[];
            end
            
            if  ~(exist(file_path_name, 'file') == 2) || isempty(app.txWaveform_resampled)
                uialert(app.ESCWaveGen,'Please Select a Folder for Radar Measurment files and/or LTE folder','File not found');
                
            else
                try
                    %assignin('base','generateWaveform','app.generateWaveform')
                    if app.ShowSIR.Value
                    pksFileName=strcat(file_path_name(1:end-length('dec_shift.dat')),'pks.mat');
                    rdr=load(pksFileName);
                    app.DebugLbl.Text=num2str(rdr.locs);
                    end
                    app.generateWaveform=setMeasFile(app.generateWaveform,file_path_name);
                    app.generateWaveform=setRadarMeasFileNum(app.generateWaveform,app.fileNo);
                    if ~app.SetRadarGainManually.Value
                    app.generateWaveform=setRfGain(app.generateWaveform);
                    else
                        app.generateWaveform=setRfGain(app.generateWaveform,app.RadarGainEdt.Value);
                    end
                        
                    app.generateWaveform=initFile(app.generateWaveform);
                    seekPositionSamples=floor(app.Parameters.Basic.StartOnFile/(1/app.Fs));
                    app.generateWaveform=setSeekPositionSamples(app.generateWaveform,seekPositionSamples);
                    samplesPerSegment=floor(app.Parameters.Basic.SegmentPreviewTime/(1/app.Fs));
                    app.generateWaveform.samplesPerSegment=samplesPerSegment;
                    
                    app.generateWaveform.errorColl=[];
                    
                    %app.generateWaveform.saveScaleFactor=app.SaveScaleFactor.Value;
                    
                    Ts=1/app.Fs;
                    NTot=floor(app.Parameters.Basic.TotalPreviewTime/Ts);
                    NLoop=floor(NTot/samplesPerSegment);
                    I=0;
                    t0=0;
                    if app.DisplayPreviewState==2
                        app.hTScope.TimeSpan=app.Parameters.Basic.SegmentPreviewTime;
                    end
                    app.ReadyStateLbl.Text='Running...';
                    app.ReadyStateLbl.FontColor=[1.00,0.00,0.00];
                    app.SIRLbl.Text='';
                    while (~(app.StopSimState) && (I<NLoop))
                        I=I+1;
                        
                        app.generateWaveform.LTEChState=app.Parameters.Signal.LTEChannelStatus;
                        app.generateWaveform.AWGNStatus=app.Parameters.Signal.AWGNStatus;
                        app.generateWaveform.AWGNVar=app.Parameters.Signal.AWGNVariance;
                        
                        app.generateWaveform.radarStatus=app.Parameters.SignalStatus.Radar;
                        app.generateWaveform.LTEStatus=[app.Parameters.SignalStatus.LTE1  app.Parameters.SignalStatus.LTE2];
                        app.generateWaveform.radarFreqOffset=[app.Parameters.FrequencyOffsetMHz.Radar];
                        app.generateWaveform.LTEFreqOffset=[app.Parameters.FrequencyOffsetMHz.LTE1 app.Parameters.FrequencyOffsetMHz.LTE2];
                        app.generateWaveform.radarGain=app.Parameters.Gain.Radar;
                        app.generateWaveform.LTEGain=[app.Parameters.Gain.LTE1 app.Parameters.Gain.LTE2];
                        
                        [app.generateWaveform,t0,interfBndPowr]=mixSignal(app.generateWaveform,t0,app.txWaveform_resampled,app.ShowSIR.Value,...
                            [app.Parameters.FrequencyOffsetMHz.Radar-0.5e6 app.Parameters.FrequencyOffsetMHz.Radar+0.5e6]);
            
                        switch app.DisplayPreviewState
                            case 1
                               app.hSpecAn(app.generateWaveform.signalOut)
                            case 2
                                app.hTScope(abs(app.generateWaveform.signalOut))
                            case 3
                             app.hSpectr(app.generateWaveform.signalOut)
                        end
                        
                        if app.ShowSIR.Value
                            %bndPower need to return bandpower from waveform mix
                            SIRtxt=sprintf(' mean=%f \n max=%f \n min=%f',10*log10(mean((app.Parameters.Gain.Radar*rdr.pks).^2)/interfBndPowr),...
                                10*log10(max((app.Parameters.Gain.Radar*rdr.pks).^2)/interfBndPowr),...
                                10*log10(min((app.Parameters.Gain.Radar*rdr.pks).^2)/interfBndPowr));
                            app.SIRLbl.Text=SIRtxt;
                            %app.SIRLbl.Text=num2str(interfBndPowr);
                        end
                    end
                    
                catch msg
                    uialert(app.ESCWaveGen,msg.message,'Error: Waveform Preview');
                end
                
            end       
            
            
            %% Go back to initial state
            
            app.StopSimState=0;
            app.ReadyStateLbl.Text='Ready...';
            app.ReadyStateLbl.FontColor=[0.07,0.53,0.07];
            
        end

        % Selection changed function: DisplayPreview
        function DisplayPreviewSelectionChanged(app, event)
            DisplaySelect= app.DisplayPreview.SelectedObject;
            switch DisplaySelect.Text
                case 'Frequency'
                    app.DisplayPreviewState=1;
                    app.hTScope.hide;
                    app.hSpectr.hide;
                    app.hSpecAn.show;
                case 'Time'
                    app.DisplayPreviewState=2;
                    app.hSpecAn.hide;
                    app.hSpectr.hide;
                    app.hTScope.show;
                case 'Spectrogram'
                    app.DisplayPreviewState=3;
                    app.hTScope.hide;
                    app.hSpecAn.hide;
                    app.hSpectr.show;
            end
            %app.DebugLbl.Text=num2str(app.DisplayPreviewState);
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            app.StopSimState=1;
            app.ReadyStateLbl.Text='Ready...';
            app.ReadyStateLbl.FontColor=[0.07,0.53,0.07];
        end

        % Value changed function: SwitchLTE1
        function SwitchLTE1ValueChanged(app, event)
            app.LED_LTE1.Enable = app.SwitchLTE1.Value;
            app.Parameters.SignalStatus.LTE1=OnOff2logicfunc(app,app.SwitchLTE1.Value);
        end

        % Value changed function: SwitchLTE2
        function SwitchLTE2ValueChanged(app, event)
            app.LED_LTE2.Enable = app.SwitchLTE2.Value;
            app.Parameters.SignalStatus.LTE2=OnOff2logicfunc(app,app.SwitchLTE2.Value);
        end

        % Value changing function: SliderFreqOffsetRadar
        function SliderFreqOffsetRadarValueChanging(app, event)
            %changingValue = event.Value;
            app.Parameters.FrequencyOffsetMHz.Radar=event.Value;
            app.RadarFreqNum.Value= app.Parameters.FrequencyOffsetMHz.Radar;
            % app.DebugLbl.Text=num2str(changingValue);
        end

        % Value changed function: RadarFreqNum
        function RadarFreqNumValueChanged(app, event)
            app.Parameters.FrequencyOffsetMHz.Radar=app.RadarFreqNum.Value;
            app.SliderFreqOffsetRadar.Value =app.Parameters.FrequencyOffsetMHz.Radar;
            
        end

        % Value changing function: SliderFreqOffsetLTE1
        function SliderFreqOffsetLTE1ValueChanging(app, event)
            %changingValue = event.Value;
            app.Parameters.FrequencyOffsetMHz.LTE1=event.Value;
            app.LTE1FreqNum.Value=app.Parameters.FrequencyOffsetMHz.LTE1;
        end

        % Value changed function: LTE1FreqNum
        function LTE1FreqNumValueChanged(app, event)
            app.Parameters.FrequencyOffsetMHz.LTE1 = app.LTE1FreqNum.Value;
            app.SliderFreqOffsetLTE1.Value=app.Parameters.FrequencyOffsetMHz.LTE1;
        end

        % Value changing function: SliderFreqOffsetLTE2
        function SliderFreqOffsetLTE2ValueChanging(app, event)
            app.Parameters.FrequencyOffsetMHz.LTE2=event.Value;
            app.LTE2FreqNum.Value=app.Parameters.FrequencyOffsetMHz.LTE2;
        end

        % Value changed function: LTE2FreqNum
        function LTE2FreqNumValueChanged(app, event)
            app.Parameters.FrequencyOffsetMHz.LTE2= app.LTE2FreqNum.Value;
            app.SliderFreqOffsetLTE2.Value=app.Parameters.FrequencyOffsetMHz.LTE2;
        end

        % Button pushed function: SelectRadarMetaDataFile
        function SelectRadarMetaDataFileButtonPushed(app, event)
            [app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_name,...
                app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_path] = ...
                uigetfile({'*.xlsx'},'Radar Meta Data File Selector');
            MetaRadar_info_full_path=fullfile(app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_path,...
                app.Parameters.Basic.DefaultDirectories.RadarMeasInfoFile_name);
            %assignin('base','MetaRadar_info_full_path',MetaRadar_info_full_path);
            if (exist(MetaRadar_info_full_path, 'file') == 2) %&& ismethod(app.generateWaveform,'setRadarMetaFile')
                
                %app.RadarInfo=read_xsl_info_f(MetaRadar_info_full_path);
                app.generateWaveform=setRadarMetaFile(app.generateWaveform,MetaRadar_info_full_path);
                app.generateWaveform=readRadarMeta(app.generateWaveform);
                app.RadarMetaFileLbl.Text=MetaRadar_info_full_path;
                app.SelectRadarMetaDataFile.Enable= 'off';
                
                
            else
                uialert(app.ESCWaveGen,'No Meta data file Selected and/ or select Radar measurement dir first','Invalid File');
            end
            
        end

        % Value changed function: LTEWaveformDuration
        function LTEWaveformDurationValueChanged(app, event)
            app.Parameters.Basic.LTEWaveformDuration= app.LTEWaveformDuration.Value;
            
        end

        % Value changed function: ShowSIR
        function ShowSIRValueChanged(app, event)
            value = app.ShowSIR.Value;
            
        end

        % Button pushed function: SelectWaveformFolderPlayer
        function SelectWaveformFolderPlayerButtonPushed(app, event)
            dialogText='Select waveforms folder to play';
            defaultExt='dat';
            app.folderBrowser=setDefaultDir(app.folderBrowser,app.Parameters.Basic.DefaultDirectories.RadarDir);
            app.folderBrowser=setDialogText(app.folderBrowser,dialogText);
            app.folderBrowser=setDefaultExt(app.folderBrowser,defaultExt);
            app.folderBrowser=SelectFolder(app.folderBrowser,app.ESCWaveGen);
            fileNames=getFileNames(app.folderBrowser,'withext');
            if ~isempty(fileNames)
                app.SelectWaveformFilePlayer.Items=fileNames;
                WaveformsPaths=getFullFileNamesWithPath(app.folderBrowser,'withext');
                app.SelectWaveformFilePlayer.ItemsData=WaveformsPaths;%1:length(fileNames);
                % set selected to the first file
                app.SelectWaveformFilePlayer.Value=char(app.SelectWaveformFilePlayer.ItemsData(1));
                
                app.playWaveform=signalFile;
                app.playWaveform=setRfGain(app.playWaveform,app.SetdataScaleGainEdt.Value);
                %samplesPerSegment=180*1024;
                app.playWaveform=setSamplesPerSegment(app.playWaveform,app.playerSamplesPerSegment);
                %{
                app.playWaveform=setMeasFile(app.playWaveform,app.SelectWaveformFilePlayer.Value);
                app.playWaveform=initFile(app.playWaveform);
                seekPositionSamples=0;
                app.playWaveform=setSeekPositionSamples(app.playWaveform,seekPositionSamples);
                %}
                setUpWaveformPlayer(app);
            end
            
        end

        % Value changed function: SelectWaveformFilePlayer
        function SelectWaveformFilePlayerValueChanged(app, event)
            %value = app.SelectWaveformFilePlayer.Value;
            %cdIfNotDepComp(app,app.dspDir);
            %if ~isempty(app.SelectWaveformFilePlayer.Value)
            
            %app.PlayerFileInfo.Text=['selected file time in sec=',num2str(timeSecPerFile)];
            %{
            app.playWaveform=setMeasFile(app.playWaveform,app.SelectWaveformFilePlayer.Value);
            app.playWaveform=initFile(app.playWaveform);
            seekPositionSamples=0;
            app.playWaveform=setSeekPositionSamples(app.playWaveform,seekPositionSamples);
            %cdIfNotDepComp(app,app.appDir);
            %assignin('base','value',value)
            if exist([app.SelectWaveformFilePlayer.Value(1:end-3),'json'],'file')==2
                txtSignal=fileread([app.SelectWaveformFilePlayer.Value(1:end-3),'json']);
                txtSignal = regexprep(txtSignal,'{','{\n');
                txtSignal = regexprep(txtSignal,'}','\n}');
                txtSignal = regexprep(txtSignal,',',',\n');
                app.WaveformInfo.Value=txtSignal;
                app.WaveformInfo.Visible='on';
                %curRightIndx=regexp(newStr, '{')
                %curLeftIndx=regexp(newStr, '}')
            else
                %app.WaveformInfo.Visible='off';
                FileInfo=dir(app.SelectWaveformFilePlayer.Value);
                timeSecPerFile=(1/app.Fs)*FileInfo.bytes/4;
                app.WaveformInfo.Value=['Total process time in sec=',num2str(timeSecPerFile)];
            end
            %}
            setUpWaveformPlayer(app)
        end

        % Selection changed function: DisplayPlayer
        function DisplayPlayerSelectionChanged(app, event)
            
              DisplayPlayerSelect= app.DisplayPlayer.SelectedObject;
            switch  DisplayPlayerSelect.Text
                case 'Frequency'
                    app.DisplayPlayState=1;
                    app.hTScope.hide;
                    app.hSpectr.hide;
                    %loadSpectrumAnalyzer(app);
                    app.hSpecAn.show;
                case 'Time'
                    app.DisplayPlayState=2;
                    app.hSpecAn.hide;
                    app.hSpectr.hide;
                    app.hTScope.show;
                case 'Spectrogram'
                    app.DisplayPlayState=3;
                    app.hTScope.hide;
                    %setSpectAsSpectrogram(app);
                    app.hSpecAn.hide;
                    app.hSpectr.show;
            end
            
            
        end

        % Button pushed function: PlayPlayer
        function PlayPlayerButtonPushed(app, event)
            try
                
                switch app.DisplayPlayState
                    case 1
                        app.hSpecAn.show;
                        app.hTScope.hide;
                        app.hSpectr.hide;
                    case 2
                        app.hSpecAn.hide;
                        app.hTScope.show;
                        app.hSpectr.hide;
                    case 3
                        app.hSpecAn.hide;
                        app.hTScope.hide;
                        app.hSpectr.show;
                end
                
                FileInfo = dir(app.SelectWaveformFilePlayer.Value);
                numSamplesPerFile=FileInfo.bytes/4;
                NLoop=floor(numSamplesPerFile/app.playerSamplesPerSegment);
                app.hTScope.TimeSpan=app.playerSamplesPerSegment*1/app.Fs;
                %NLoop=100;
                I=0;
                %cdIfNotDepComp(app,app.dspDir);
                while (~(app.StopPlayState) && (I<NLoop))
                    
                    radarMeasData=readMeasData(app.playWaveform)*1/app.Parameters.Signal.SaveScaleFactor;
                    app.playWaveform=seekNextPositionSamples(app.playWaveform);
                    %assignin('base','radarMeasData',radarMeasData)
                    if ~isempty(radarMeasData)
            
                        switch app.DisplayPlayState
                            case 1
                                app.hSpecAn(radarMeasData)
                            case 2
                                app.hTScope(abs(radarMeasData))
                            case 3
                                app.hSpectr(radarMeasData)
                        end
                        
                    else
                        app.StopPlayState=1;
                        seekPositionSamples=0;
                        app.playWaveform=setSeekPositionSamples(app.playWaveform,seekPositionSamples);
                    end
                    I=I+1;
                end
                app.StopPlayState=0;
            catch msg
               uialert(app.ESCWaveGen,msg.message,'Error: Waveform player');
            end
        end

        % Button pushed function: StopPlayer
        function StopPlayerButtonPushed(app, event)
            app.StopPlayState=1;
        end

        % Button pushed function: LoadPreviewParameters
        function LoadPreviewParametersButtonPushed(app, event)
            app.SingleFileGenerationTable1.ColumnName={'Radar','LTE1','LTE2'};
            app.SingleFileGenerationTable1.RowName={'status';'Frequncy Offset';'Gain'};
            %app.SingleFileGenerationTable1.Data={true,true,false;1,2,3;11,22,33};
            app.SingleFileGenerationTable1.Data=[struct2cell(app.Parameters.SignalStatus).';...
                struct2cell(app.Parameters.FrequencyOffsetMHz).';...
                struct2cell(app.Parameters.Gain).'];
            app.SingleFileGenerationTable1.ColumnEditable=[true,true,true];
            
            app.SingleFileGenerationTable2.ColumnName={'Value'};
            app.SingleFileGenerationTable2.RowName={'Total Process Time';'Radar Start Time';'Segment Process Time';...
                'AWGN Status';'AWGN Variance';'Save Scale Factor';'LTE Channel Status'};
            SignalCell=struct2cell(app.Parameters.Signal);
            app.SingleFileGenerationTable2.Data=SignalCell(1:7);
            app.SingleFileGenerationTable2.ColumnEditable=[true];
            app.LTEChannelTypeTbl.ColumnName={'LTE1','LTE2'};
            app.LTEChannelTypeTbl.ColumnFormat={app.ThreeGPPChTypes,app.ThreeGPPChTypes};
            % app.LTEChannelTypeTbl.ColumnFormat={'EPA5Hz'    'EVA5Hz'    'EVA70Hz'    'ETU70Hz'    'ETU300Hz'};
            app.LTEChannelTypeTbl.RowName={'Channel Type'};
            app.LTEChannelTypeTbl.Data=struct2cell(app.Parameters.Signal.LTEChannelType)';
            %app.LTEChannelTypeTbl.ColumnFormat={{'EPA5Hz'    'EVA5Hz'    'EVA70Hz'    'ETU70Hz'    'ETU300Hz'} {'EPA5Hz'    'EVA5Hz'    'EVA70Hz'    'ETU70Hz'    'ETU300Hz'}};
            app.LTEChannelTypeTbl.ColumnEditable=[true];
            
            Tbl2Data7=app.SingleFileGenerationTable2.Data(7);
            app.LTEChannelTypeTbl.Enable=logic2Enablefunc(app,Tbl2Data7{1});
            
            %app.ThreeGPPChPrCp.Items=app.ThreeGPPChTypes;
        end

        % Value changing function: KnobGainRadar
        function KnobGainRadarValueChanging(app, event)
            app.Parameters.Gain.Radar=event.Value;
            app.GainRadarNum.Value= app.Parameters.Gain.Radar;
        end

        % Value changed function: GainRadarNum
        function GainRadarNumValueChanged(app, event)
            app.Parameters.Gain.Radar = app.GainRadarNum.Value;
            app.KnobGainRadar.Value=app.Parameters.Gain.Radar;
        end

        % Value changing function: KnobGainLTE1
        function KnobGainLTE1ValueChanging(app, event)
            app.Parameters.Gain.LTE1=event.Value;
            app.GainLTE1Num.Value= app.Parameters.Gain.LTE1;
            
        end

        % Value changed function: GainLTE1Num
        function GainLTE1NumValueChanged(app, event)
            app.Parameters.Gain.LTE1 = app.GainLTE1Num.Value;
            app.KnobGainLTE1.Value=app.Parameters.Gain.LTE1;
            
        end

        % Value changing function: KnobGainLTE2
        function KnobGainLTE2ValueChanging(app, event)
            app.Parameters.Gain.LTE2=event.Value;
            app.GainLTE2Num.Value= app.Parameters.Gain.LTE2;
        end

        % Value changed function: GainLTE2Num
        function GainLTE2NumValueChanged(app, event)
            app.Parameters.Gain.LTE2 = app.GainLTE2Num.Value;
            app.KnobGainLTE2.Value=app.Parameters.Gain.LTE2;
        end

        % Button pushed function: SaveWaveformAs
        function SaveWaveformAsButtonPushed(app, event)
            [WaveformSavefile, WaveformSavepath] = uiputfile(...
                {'*.dat';'*.*'},...
                'Save waveform as','D:\Spectrum-Share');
            if (sum(WaveformSavefile~=0) && sum(WaveformSavepath~=0))
                app.SaveWaveformFilePath=fullfile(WaveformSavepath, WaveformSavefile);
                app.SaveWavefomAsedt.Value=app.SaveWaveformFilePath;
            end
        end

        % Callback function
        function TotalPreviewTimeValueChanged(app, event)
            app.Parameters.Basic.TotalPreviewTime = app.TotalPreviewTime.Value;
            
        end

        % Callback function
        function SegmentPreviewTimeValueChanged(app, event)
            app.Parameters.Basic.SegmentPreviewTime = app.SegmentPreviewTime.Value;
            
        end

        % Value changed function: StartOnFile
        function StartOnFileValueChanged(app, event)
            app.Parameters.Basic.StartOnFile = app.StartOnFile.Value;
            
        end

        % Value changed function: LTEChannelState
        function LTEChannelStateValueChanged(app, event)
            value=app.LTEChannelState.Value;
            app.Parameters.Signal.LTEChannelStatus = value;
            app.ChannelTypeLTE1.Enable=logic2Enablefunc(app,value);
            app.ChannelTypeLTE2.Enable=logic2Enablefunc(app,value);
            
        end

        % Value changing function: AWGNVar
        function AWGNVarValueChanging(app, event)
            app.Parameters.Signal.AWGNVariance = event.Value;
            app.AWGNVarNum.Value=app.Parameters.Signal.AWGNVariance;
        end

        % Value changed function: AWGNVarNum
        function AWGNVarNumValueChanged(app, event)
            app.Parameters.Signal.AWGNVariance = app.AWGNVarNum.Value;
            app.AWGNVar.Value=app.Parameters.Signal.AWGNVariance;
        end

        % Value changed function: AWGN
        function AWGNValueChanged(app, event)
            app.Parameters.Signal.AWGNStatus = app.AWGN.Value;
            
        end

        % Callback function
        function SingleFileGenerationTable2CellEdit(app, event)
            if event.Indices==[7 1]
                % check if LTE channel is on and enable channel type Table 
                %{
                if ~ismember(app.SingleFileGenerationTable2.Data(5),app.ThreeGPPChPrCp.Items)
                    app.SingleFileGenerationTable2.Data(5)={event.PreviousData};
                    wrnMsg=sprintf('please select one of these channel types:\n %s', strjoin(app.ThreeGPPChPrCp.Items));
                    uialert(app.ESCWaveGen,wrnMsg,'Invalid Channel Type');
                end
                %}
                Tbl2Data7=app.SingleFileGenerationTable2.Data(7);
                %assignin('base','data7',app.SingleFileGenerationTable2.Data(7));
                app.LTEChannelTypeTbl.Enable=logic2Enablefunc(app,Tbl2Data7{1});
                
            end
        end

        % Callback function
        function SingleFileGenerationTable2CellSelection(app, event)
            %{
            if event.Indices==[5 1]
                app.ThreeGPPChPrCp.Visible='on';
            else
                app.ThreeGPPChPrCp.Visible='off';
            end
            %}
        end

        % Button pushed function: GenerateThisWaveform
        function GenerateThisWaveformButtonPushed(app, event)
            %{
            assignin('base','SingleFileGenerationTable1Col',app.SingleFileGenerationTable1.ColumnName);
            assignin('base','SingleFileGenerationTable1Row',app.SingleFileGenerationTable1.RowName);
            assignin('base','SingleFileGenerationTable1Data',app.SingleFileGenerationTable1.Data);
            assignin('base','SingleFileGenerationTable1',app.SingleFileGenerationTable1);
            assignin('base','SingleFileGenerationTable2Col',app.SingleFileGenerationTable2.ColumnName);
            assignin('base','SingleFileGenerationTable2Row',app.SingleFileGenerationTable2.RowName);
            assignin('base','SingleFileGenerationTable2Data',app.SingleFileGenerationTable2.Data);
            assignin('base','SingleFileGenerationTable2',app.SingleFileGenerationTable2);
            [~,fileName,extn]=fileparts(app.SaveWavefomAsedt.Value);
            assignin('base','SaveWavefomAsedt',[fileName,extn]);
            %}
            InterfaceObj=findobj(app.ESCWaveGen,'Enable','on');
            set(InterfaceObj,'Enable','off');
            
            try
                [filePath,fileName,extn]=fileparts(app.SaveWavefomAsedt.Value);
                signalStruct=cell2struct(app.SingleFileGenerationTable2.Data,strrep(app.SingleFileGenerationTable2.RowName,' ',''),1);
                sfgStruct=cell2struct(app.SingleFileGenerationTable1.Data,strrep(app.SingleFileGenerationTable1.ColumnName,' ',''),2);
                sfgFLDNMS=strrep(app.SingleFileGenerationTable1.RowName,' ','');
                LTEChTypeStruct=cell2struct(app.LTEChannelTypeTbl.Data,strrep(app.LTEChannelTypeTbl.ColumnName,' ',''),2);
                LTEChTypeFLDNMS=strrep(app.LTEChannelTypeTbl.RowName,' ','');
                %assignin('base','sfgStruct',sfgStruct);
                %assignin('base','sfgFLDNMS',sfgFLDNMS);
                
                signalPar=struct('FileName',[fileName,extn],'Signal',signalStruct);%,char(LTEChTypeFLDNMS(1)),LTEChTypeStruct,...
                %char(sfgFLDNMS(1)),sfgStruct(1),char(sfgFLDNMS(2)),sfgStruct(2),char(sfgFLDNMS(3)),sfgStruct(3));
                
                signalPar.Signal.(char(LTEChTypeFLDNMS(1)))=LTEChTypeStruct;
                for I=1:length(sfgFLDNMS)
                    signalPar.Signal.(char(sfgFLDNMS(I)))=sfgStruct(I);
                end
                %signalPar.Signal.(char(LTEChTypeFLDNMS(1)))=LTEChTypeStruct;
                
                %assignin('base','signalPar',signalPar);
                seekPositionSamples=0;
                app.generateWaveform=resetSignalFile(app.generateWaveform);
                app.generateWaveform.samplesPerSegment=floor(signalPar.Signal.SegmentProcessTime/(1/app.Fs));
                app.generateWaveform.radarStatus=signalPar.Signal.status.Radar;
                app.generateWaveform.LTEStatus=[signalPar.Signal.status.LTE1 signalPar.Signal.status.LTE2];
                app.generateWaveform.LTEChState=signalPar.Signal.LTEChannelStatus;
                app.generateWaveform.AWGNStatus=signalPar.Signal.AWGNStatus;
                app.generateWaveform.radarFreqOffset=signalPar.Signal.FrequncyOffset.Radar;
                app.generateWaveform.LTEFreqOffset=[signalPar.Signal.FrequncyOffset.LTE1 signalPar.Signal.FrequncyOffset.LTE2];
                app.generateWaveform.radarGain=signalPar.Signal.Gain.Radar;
                app.generateWaveform.LTEGain=[signalPar.Signal.Gain.LTE1 signalPar.Signal.Gain.LTE2];
                app.generateWaveform.AWGNVar=signalPar.Signal.AWGNVariance;
                app.generateWaveform.saveScaleFactor=signalPar.Signal.SaveScaleFactor;
                app.generateWaveform.errorColl=[];
                
                app.SingleFileGenIndlbl.Text=['Generating file:',fileName,'....'];
                %app.generateWaveform=setUpLTECh(app.generateWaveform,struct2cell(app.Parameters.Signal.LTEChannelType)',app.LTEChNum);
                app.generateWaveform=setUpLTECh(app.generateWaveform,struct2cell(signalPar.Signal.ChannelType)',app.LTEChNum);
                                    
            
                    if ~app.SetRadarGainManually.Value
                app.generateWaveform=setUpRadar(app.generateWaveform,char(app.RadarWaveformsPaths(app.fileNo)),app.RadarMetaFileLbl.Text,...
                    app.fileNo,seekPositionSamples);
                    else
                         app.generateWaveform=setMeasFile(app.generateWaveform,char(app.RadarWaveformsPaths(app.fileNo)));
                         %app.generateWaveform=setRadarMeasFileNum(app.generateWaveform,app.fileNo);
                         app.generateWaveform=setRfGain(app.generateWaveform,app.RadarGainEdt.Value);
                         app.generateWaveform=setSeekPositionSamples(app.generateWaveform,seekPositionSamples);
                         app.generateWaveform=initFile(app.generateWaveform);
                    end
                
            
                tic;
                [app.generateWaveform,radarStartTimeAdj, success]=mixSignalSave(app.generateWaveform,app.txWaveform_resampled,...
                    signalPar.Signal.TotalProcessTime,signalPar.Signal.RadarStartTime,app.SaveWavefomAsedt.Value);
                if success
                    signalPar.Signal.RadarStartTime=radarStartTimeAdj;
                    SaveParametersAsPath=fullfile(filePath,[fileName,'.json']);
                    [SaveParametersFileId,errmsg_write]=fopen(SaveParametersAsPath,'w','n','UTF-8');
                    fwrite(SaveParametersFileId,jsonencode(signalPar),'char');
                end
                execTime=toc;
                app.SingleFileGenIndlbl.Text=['Ready, Last finished in ',sec2DurationChar(app,execTime)];
            catch msg
                %assignin('base','msg',msg);
                uialert(app.ESCWaveGen,msg.message,'Error: Please check waveform parameters');
            end
            set(InterfaceObj,'Enable','on');
            
        end

        % Value changed function: ParallelProcess
        function ParallelProcessValueChanged(app, event)
            app.parState = app.ParallelProcess.Value;
            if  app.parState
                app.SetNumberofWorkers.Enable='off';
                InterfaceObj=findobj(app.ESCWaveGen,'Enable','on');
                set(InterfaceObj,'Enable','off');
                app.MultiFileGenIndlbl.Text='Starting Parallel Computing Engine...';
                
                try
                    NumWorkers=app.SetNumberofWorkers.Value; %Set to desired number of workers (do not exceed # cores in system)
                    poolobj = gcp('nocreate'); % If no pool, do not create new one.
                    
                    if isempty(poolobj)
                        poolsize = 0;
                    else
                        poolsize = poolobj.NumWorkers;
                    end
                    
                    if poolsize~=NumWorkers
                        delete(poolobj)
                        poolobj=parpool(NumWorkers);
                    end
                    app.AvailableNumberofWorkers.Text=['Available Number of Workers=',num2str(NumWorkers)];
                catch msg
                    uialert(app.ESCWaveGen,msg.message,'Error: starting parallel process');
                    app.parState=false;
                    app.ParallelProcess.Value=false;
                    app.SetNumberofWorkers.Enable='on';
                end
                %assignin('base','poolobjApp',poolobj)
                set(InterfaceObj,'Enable','on');
                app.MultiFileGenIndlbl.Text='Ready';
            else
                app.SetNumberofWorkers.Enable='on';
            end          
        end

        % Value changed function: ChannelTypeLTE1
        function ChannelTypeLTE1ValueChanged(app, event)
            app.Parameters.Signal.LTEChannelType.LTE1 = app.ChannelTypeLTE1.Value;
            app.generateWaveform=setUpLTECh(app.generateWaveform,struct2cell(app.Parameters.Signal.LTEChannelType)',app.LTEChNum);
        end

        % Value changed function: ChannelTypeLTE2
        function ChannelTypeLTE2ValueChanged(app, event)
            app.Parameters.Signal.LTEChannelType.LTE2 = app.ChannelTypeLTE2.Value;
            app.generateWaveform=setUpLTECh(app.generateWaveform,struct2cell(app.Parameters.Signal.LTEChannelType)',app.LTEChNum);
        end

        % Value changed function: SaveSignalInfo
        function SaveSignalInfoValueChanged(app, event)
            app.Parameters.Basic.SaveSignalInfo=app.SaveSignalInfo.Value;
        end

        % Button pushed function: LoadDefaults
        function LoadDefaultsButtonPushed(app, event)
            try
                loadDefaults(app);
            catch msg
                uialert(app.ESCWaveGen,msg.message,'Error: Cant  load default settings, make sure preview is stopped');
            end
        end

        % Button pushed function: ResetSpectrumAnalyzerandTimeSope
        function ResetSpectrumAnalyzerandTimeSopeButtonPushed(app, event)
            try
                app.hSpecAn.hide;
                app.hTScope.hide;
                app.hSpectr.hide;
                
                app.hSpecAn = dsp.SpectrumAnalyzer;
                app.hSpecAn.SampleRate =app.Fs;
                app.hTScope= dsp.TimeScope;
                app.hTScope.SampleRate=app.Fs;
                app.hSpectr = dsp.SpectrumAnalyzer;
                app.hSpectr.SampleRate =app.Fs;
                
                app.DisplayPlayState=1;
                app.DisplayPreviewState=1;
                app.FrequencyPlayer.Value=true;
                app.Frequency.Value=true;
                loadSpectrumAnalyzer(app);
                loadTimeScope(app);
                loadSpectrogram(app)
            catch msg
                uialert(app.ESCWaveGen,msg.message,'Error: Can"t reset spectrum anaylyzer and time scope');
            end
        end

        % Button pushed function: GenerateWaveforms
        function GenerateWaveformsButtonPushed(app, event)
            
        end

        % Button pushed function: SaveWaveformDir
        function SaveWaveformDirButtonPushed(app, event)
            defaultDir='';
            dialogText='Select folder to save waveforms';
            directoryName = uigetdir(defaultDir,dialogText);
            if directoryName~=0
                app.SaveWavefomsedt.Value=directoryName;
            end
        end

        % Close request function: ESCWaveGen
        function ESCWaveGenCloseRequest(app, event)
                      
            closeTxt=sprintf('Make sure to stop waveform preview/play/generation before closing! \n Confirm to close');
            selection = questdlg(closeTxt,...
                'Close ESC Waveform Generator?',...
                'Yes','No','Yes');
            switch selection
                case 'Yes'
                    %app.StopPlayState=1;
                    %pause(2);
                    %app.hSpecAn.hide();
                    %app.hTScope.hide();
                    %clear app.hSpecAn
                    %clear app.hTScope
                    %pause(2)
                    delete(app)
                case 'No'
                    return
            end
            
        end

        % Value changed function: SetRadarGainManually
        function SetRadarGainManuallyValueChanged(app, event)
            value = app.SetRadarGainManually.Value;
            if value
                app.RadarGainEdt.Enable='on';
            else
                app.RadarGainEdt.Enable='off';
            end
        end

        % Value changed function: SetdataScaleGainEdt
        function SetdataScaleGainEdtValueChanged(app, event)
            %value = app.SetdataScaleGainEdt.Value;
            app.playWaveform=setRfGain(app.playWaveform,app.SetdataScaleGainEdt.Value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ESCWaveGen = uifigure;
            app.ESCWaveGen.Name = 'ESC Waveform Generator V 1.00';
            app.ESCWaveGen.CloseRequestFcn = createCallbackFcn(app, @ESCWaveGenCloseRequest, true);
            setAutoResize(app, app.ESCWaveGen, true)
            app.TabGroup = uitabgroup(app.ESCWaveGen);
            app.SignalSources = uitab(app.TabGroup);
            app.SignalSources.Title = 'Signal Sources';
            app.SignalSources.Units = 'pixels';
            app.SelectDataFolderRadar = uibutton(app.SignalSources, 'push');
            app.SelectDataFolderRadar.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderRadarButtonPushed, true);
            app.SelectDataFolderRadar.Text = 'Select Radar Data Folder';
            app.LabelListBox = uilabel(app.SignalSources);
            app.LabelListBox.Text = 'Select File';
            app.SelectRadarFile = uilistbox(app.SignalSources);
            app.SelectRadarFile.Items = {};
            app.SelectRadarFile.ValueChangedFcn = createCallbackFcn(app, @SelectRadarFileValueChanged, true);
            app.SelectRadarFile.Value = {};
            app.SelectDataFolderLTE = uibutton(app.SignalSources, 'push');
            app.SelectDataFolderLTE.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderLTEButtonPushed, true);
            app.SelectDataFolderLTE.Text = 'Select LTE Data Folder';
            app.LTEDirLbl = uilabel(app.SignalSources);
            app.LTEDirLbl.Text = 'LTE Data Dir:';
            app.RadarDirLbl = uilabel(app.SignalSources);
            app.RadarDirLbl.Text = 'Radar Data Dir:';
            app.LTELMessageLbl = uilabel(app.SignalSources);
            app.RadarMetaFileLbl = uilabel(app.SignalSources);
            app.RadarMetaFileLbl.Text = 'Radar Meta Data File: ';
            app.SelectRadarMetaDataFile = uibutton(app.SignalSources, 'push');
            app.SelectRadarMetaDataFile.ButtonPushedFcn = createCallbackFcn(app, @SelectRadarMetaDataFileButtonPushed, true);
            app.SelectRadarMetaDataFile.Text = 'Select Radar Meta Data File:';
            app.LTEWaveformDurationSecLabel = uilabel(app.SignalSources);
            app.LTEWaveformDurationSecLabel.Text = 'LTE Waveform Duration Sec';
            app.LTEWaveformDuration = uieditfield(app.SignalSources, 'numeric');
            app.LTEWaveformDuration.ValueChangedFcn = createCallbackFcn(app, @LTEWaveformDurationValueChanged, true);
            app.SetRadarGainManually = uicheckbox(app.SignalSources);
            app.SetRadarGainManually.ValueChangedFcn = createCallbackFcn(app, @SetRadarGainManuallyValueChanged, true);
            app.SetRadarGainManually.Text = 'Set Radar Gain Manually';
            app.RadarGainEdt = uieditfield(app.SignalSources, 'numeric');
            app.WaveformPreview = uitab(app.TabGroup);
            app.WaveformPreview.Title = 'Waveform Preview';
            app.WaveformPreview.Units = 'pixels';
            app.SignalTiming = uipanel(app.WaveformPreview);
            app.SignalTiming.Title = 'Signal Timing in seconds';
            app.ForwardonfileLabel = uilabel(app.SignalTiming);
            app.ForwardonfileLabel.Text = 'Forward on file';
            app.StartOnFile = uieditfield(app.SignalTiming, 'numeric');
            app.StartOnFile.ValueChangedFcn = createCallbackFcn(app, @StartOnFileValueChanged, true);
            app.StartOnFile.Limits = [0 60];
            app.StartOnFile.Value = 1.36;
            app.TotalpreviewLabel = uilabel(app.SignalTiming);
            app.TotalpreviewLabel.Text = 'Total preview ';
            app.TotalPreviewTime = uieditfield(app.SignalTiming, 'numeric');
            app.TotalPreviewTime.ValueChangedFcn = createCallbackFcn(app, @TotalPreviewTimeValueChanged, true);
            app.TotalPreviewTime.Limits = [0.1 60];
            app.TotalPreviewTime.Value = 1;
            app.SegmentPreviewTimeLabel = uilabel(app.SignalTiming);
            app.SegmentPreviewTimeLabel.Text = 'Segment preview';
            app.SegmentPreviewTime = uieditfield(app.SignalTiming, 'numeric');
            app.SegmentPreviewTime.Limits = [0.001 1];
            app.SegmentPreviewTime.Value = 0.01;
            app.PanelLTE2 = uipanel(app.WaveformPreview);
            app.PanelLTE2.Title = 'LTE #2';
            app.LED_LTE2 = uilamp(app.PanelLTE2);
            app.LED_LTE2.Enable = 'off';
            app.SliderFreqOffsetLTE2 = uislider(app.PanelLTE2);
            app.SliderFreqOffsetLTE2.Limits = [-12 12];
            app.SliderFreqOffsetLTE2.ValueChangingFcn = createCallbackFcn(app, @SliderFreqOffsetLTE2ValueChanging, true);
            app.SliderFreqOffsetLTE2.MinorTicks = [];
            app.SliderFreqOffsetLTE2.Value = 5;
            app.Label13 = uilabel(app.PanelLTE2);
            app.Label13.Text = 'Frequency Offset MHz';
            app.Label15 = uilabel(app.PanelLTE2);
            app.Label15.Text = '';
            app.SwitchLTE2 = uiswitch(app.PanelLTE2, 'toggle');
            app.SwitchLTE2.ValueChangedFcn = createCallbackFcn(app, @SwitchLTE2ValueChanged, true);
            app.LTE2FreqNum = uieditfield(app.PanelLTE2, 'numeric');
            app.LTE2FreqNum.ValueChangedFcn = createCallbackFcn(app, @LTE2FreqNumValueChanged, true);
            app.LTE2FreqNum.Limits = [-12 12];
            app.Label14 = uilabel(app.PanelLTE2);
            app.Label14.Text = 'Gain';
            app.KnobGainLTE2 = uiknob(app.PanelLTE2, 'continuous');
            app.KnobGainLTE2.Limits = [0 1];
            app.KnobGainLTE2.ValueChangingFcn = createCallbackFcn(app, @KnobGainLTE2ValueChanging, true);
            app.KnobGainLTE2.Value = 0.0005;
            app.GainLTE2Num = uieditfield(app.PanelLTE2, 'numeric');
            app.GainLTE2Num.ValueChangedFcn = createCallbackFcn(app, @GainLTE2NumValueChanged, true);
            app.GainLTE2Num.ValueDisplayFormat = '%2.5f';
            app.ChannelTypeLTE1_2Label = uilabel(app.PanelLTE2);
            app.ChannelTypeLTE1_2Label.Text = 'Channel Type';
            app.ChannelTypeLTE2 = uidropdown(app.PanelLTE2);
            app.ChannelTypeLTE2.ValueChangedFcn = createCallbackFcn(app, @ChannelTypeLTE2ValueChanged, true);
            app.PanelLTE1 = uipanel(app.WaveformPreview);
            app.PanelLTE1.Title = 'LTE #1';
            app.LED_LTE1 = uilamp(app.PanelLTE1);
            app.LED_LTE1.Enable = 'off';
            app.SliderFreqOffsetLTE1 = uislider(app.PanelLTE1);
            app.SliderFreqOffsetLTE1.Limits = [-12 12];
            app.SliderFreqOffsetLTE1.MajorTickLabels = {'-12', '', '', '', '', '', '', '', '', '', '', '', '12'};
            app.SliderFreqOffsetLTE1.ValueChangingFcn = createCallbackFcn(app, @SliderFreqOffsetLTE1ValueChanging, true);
            app.SliderFreqOffsetLTE1.MinorTicks = [];
            app.SliderFreqOffsetLTE1.Value = -5;
            app.Label17 = uilabel(app.PanelLTE1);
            app.Label17.Text = 'Frequency Offset MHz';
            app.Label18 = uilabel(app.PanelLTE1);
            app.Label18.Text = 'Gain';
            app.KnobGainLTE1 = uiknob(app.PanelLTE1, 'continuous');
            app.KnobGainLTE1.Limits = [0 1];
            app.KnobGainLTE1.ValueChangingFcn = createCallbackFcn(app, @KnobGainLTE1ValueChanging, true);
            app.KnobGainLTE1.Value = 0.0005;
            app.Label19 = uilabel(app.PanelLTE1);
            app.Label19.Text = '';
            app.SwitchLTE1 = uiswitch(app.PanelLTE1, 'toggle');
            app.SwitchLTE1.ValueChangedFcn = createCallbackFcn(app, @SwitchLTE1ValueChanged, true);
            app.LTE1FreqNum = uieditfield(app.PanelLTE1, 'numeric');
            app.LTE1FreqNum.ValueChangedFcn = createCallbackFcn(app, @LTE1FreqNumValueChanged, true);
            app.LTE1FreqNum.Limits = [-12 12];
            app.GainLTE1Num = uieditfield(app.PanelLTE1, 'numeric');
            app.GainLTE1Num.ValueChangedFcn = createCallbackFcn(app, @GainLTE1NumValueChanged, true);
            app.GainLTE1Num.ValueDisplayFormat = '%2.5f';
            app.ChannelTypeDropDownLabel = uilabel(app.PanelLTE1);
            app.ChannelTypeDropDownLabel.Text = 'Channel Type';
            app.ChannelTypeLTE1 = uidropdown(app.PanelLTE1);
            app.ChannelTypeLTE1.ValueChangedFcn = createCallbackFcn(app, @ChannelTypeLTE1ValueChanged, true);
            app.PanelRadar = uipanel(app.WaveformPreview);
            app.PanelRadar.Title = 'Radar';
            app.RadarLED = uilamp(app.PanelRadar);
            app.RadarLED.Enable = 'off';
            app.SliderFreqOffsetRadar = uislider(app.PanelRadar);
            app.SliderFreqOffsetRadar.Limits = [-12 12];
            app.SliderFreqOffsetRadar.ValueChangingFcn = createCallbackFcn(app, @SliderFreqOffsetRadarValueChanging, true);
            app.SliderFreqOffsetRadar.MinorTicks = [];
            app.Label21 = uilabel(app.PanelRadar);
            app.Label21.Text = 'Frequency Offset MHz';
            app.Label22 = uilabel(app.PanelRadar);
            app.Label22.Text = 'Gain';
            app.KnobGainRadar = uiknob(app.PanelRadar, 'continuous');
            app.KnobGainRadar.Limits = [0 10];
            app.KnobGainRadar.ValueChangingFcn = createCallbackFcn(app, @KnobGainRadarValueChanging, true);
            app.KnobGainRadar.Value = 1;
            app.SwitchRadar = uiswitch(app.PanelRadar, 'toggle');
            app.SwitchRadar.ValueChangedFcn = createCallbackFcn(app, @SwitchRadarValueChanged, true);
            app.Label23 = uilabel(app.PanelRadar);
            app.Label23.Text = '';
            app.RadarFreqNum = uieditfield(app.PanelRadar, 'numeric');
            app.RadarFreqNum.ValueChangedFcn = createCallbackFcn(app, @RadarFreqNumValueChanged, true);
            app.RadarFreqNum.Limits = [-12 12];
            app.GainRadarNum = uieditfield(app.PanelRadar, 'numeric');
            app.GainRadarNum.ValueChangedFcn = createCallbackFcn(app, @GainRadarNumValueChanged, true);
            app.GainRadarNum.ValueDisplayFormat = '%2.5f';
            app.Waveforms = uipanel(app.WaveformPreview);
            app.Waveforms.Title = 'Waveforms';
            app.Stop = uibutton(app.Waveforms, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Text = 'Stop';
            app.Preview = uibutton(app.Waveforms, 'push');
            app.Preview.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.Preview.Text = 'Preview';
            app.DisplayPreview = uibuttongroup(app.Waveforms);
            app.DisplayPreview.SelectionChangedFcn = createCallbackFcn(app, @DisplayPreviewSelectionChanged, true);
            app.DisplayPreview.Title = 'Signal Display';
            app.Frequency = uiradiobutton(app.DisplayPreview);
            app.Frequency.Text = 'Frequency';
            app.Frequency.Value = true;
            app.Time = uiradiobutton(app.DisplayPreview);
            app.Time.Text = 'Time';
            app.Spectrogram = uiradiobutton(app.DisplayPreview);
            app.Spectrogram.Text = 'Spectrogram';
            app.ReadyStateLbl = uilabel(app.Waveforms);
            app.ReadyStateLbl.Text = 'Ready...';
            app.SIRLbl = uilabel(app.Waveforms);
            app.SIRLbl.Text = 'SIR';
            app.ShowSIR = uicheckbox(app.Waveforms);
            app.ShowSIR.ValueChangedFcn = createCallbackFcn(app, @ShowSIRValueChanged, true);
            app.ShowSIR.Text = 'Show SIR';
            app.LTEChannelState = uicheckbox(app.WaveformPreview);
            app.LTEChannelState.ValueChangedFcn = createCallbackFcn(app, @LTEChannelStateValueChanged, true);
            app.LTEChannelState.Text = 'LTE Channel';
            app.AWGN = uicheckbox(app.WaveformPreview);
            app.AWGN.ValueChangedFcn = createCallbackFcn(app, @AWGNValueChanged, true);
            app.AWGN.Text = 'AWGN';
            app.AWGNVar = uislider(app.WaveformPreview);
            app.AWGNVar.Limits = [1e-11 1e-06];
            app.AWGNVar.ValueChangingFcn = createCallbackFcn(app, @AWGNVarValueChanging, true);
            app.AWGNVar.Value = 1e-10;
            app.AWGNVarNum = uieditfield(app.WaveformPreview, 'numeric');
            app.AWGNVarNum.ValueChangedFcn = createCallbackFcn(app, @AWGNVarNumValueChanged, true);
            app.AWGNVarNum.ValueDisplayFormat = '%e';
            app.LoadDefaults = uibutton(app.WaveformPreview, 'push');
            app.LoadDefaults.ButtonPushedFcn = createCallbackFcn(app, @LoadDefaultsButtonPushed, true);
            app.LoadDefaults.Text = 'Load Defaults';
            app.WaveformGenerationTab = uitab(app.TabGroup);
            app.WaveformGenerationTab.Title = 'Waveform Generation';
            app.WaveformGenerationTab.Units = 'pixels';
            app.SingleFileGenerationPanel = uipanel(app.WaveformGenerationTab);
            app.SingleFileGenerationPanel.Title = 'Single File Generation';
            app.SaveWaveformAs = uibutton(app.SingleFileGenerationPanel, 'push');
            app.SaveWaveformAs.ButtonPushedFcn = createCallbackFcn(app, @SaveWaveformAsButtonPushed, true);
            app.SaveWaveformAs.Text = 'Save Waveform As';
            app.SingleFileGenIndlbl = uilabel(app.SingleFileGenerationPanel);
            app.SingleFileGenIndlbl.Text = 'Ready';
            app.SaveWavefomAsedt = uieditfield(app.SingleFileGenerationPanel, 'text');
            app.GenerateThisWaveform = uibutton(app.SingleFileGenerationPanel, 'push');
            app.GenerateThisWaveform.ButtonPushedFcn = createCallbackFcn(app, @GenerateThisWaveformButtonPushed, true);
            app.GenerateThisWaveform.Text = 'Generate This Waveform';
            app.MultipleFileGenerationPanel = uipanel(app.WaveformGenerationTab);
            app.MultipleFileGenerationPanel.Title = 'Multiple File Generation';
            app.SaveWaveformDir = uibutton(app.MultipleFileGenerationPanel, 'push');
            app.SaveWaveformDir.ButtonPushedFcn = createCallbackFcn(app, @SaveWaveformDirButtonPushed, true);
            app.SaveWaveformDir.Text = 'Save Waveform Dir';
            app.GenerateWaveforms = uibutton(app.MultipleFileGenerationPanel, 'push');
            app.GenerateWaveforms.ButtonPushedFcn = createCallbackFcn(app, @GenerateWaveformsButtonPushed, true);
            app.GenerateWaveforms.Text = 'Generate Waveforms';
            app.SaveWavefomsedt = uieditfield(app.MultipleFileGenerationPanel, 'text');
            app.ParallelProcess = uicheckbox(app.MultipleFileGenerationPanel);
            app.ParallelProcess.ValueChangedFcn = createCallbackFcn(app, @ParallelProcessValueChanged, true);
            app.ParallelProcess.Text = 'Parallel Process';
            app.SetNumberofWorkersLabel = uilabel(app.MultipleFileGenerationPanel);
            app.SetNumberofWorkersLabel.Text = 'Set Number of Workers';
            app.SetNumberofWorkers = uispinner(app.MultipleFileGenerationPanel);
            app.SetNumberofWorkers.Limits = [1 100];
            app.SetNumberofWorkers.ValueDisplayFormat = '%.0f';
            app.SetNumberofWorkers.Value = 20;
            app.AvailableNumberofWorkers = uilabel(app.MultipleFileGenerationPanel);
            app.AvailableNumberofWorkers.Text = 'Available Number of Workers=';
            app.MultiFileGenIndlbl = uilabel(app.MultipleFileGenerationPanel);
            app.MultiFileGenIndlbl.Text = 'Ready';
            app.FileNamePrefixEditFieldLabel = uilabel(app.MultipleFileGenerationPanel);
            app.FileNamePrefixEditFieldLabel.Text = 'File Name Prefix';
            app.FileNamePrefixedt = uieditfield(app.MultipleFileGenerationPanel, 'text');
            app.NumberofFilesSpinnerLabel = uilabel(app.MultipleFileGenerationPanel);
            app.NumberofFilesSpinnerLabel.Text = 'Number of Files';
            app.NumberofFilesSpinner = uispinner(app.MultipleFileGenerationPanel);
            app.ParametersPanel = uipanel(app.WaveformGenerationTab);
            app.ParametersPanel.Title = 'Parameters';
            app.SingleFileGenerationTable1 = uitable(app.ParametersPanel);
            app.SingleFileGenerationTable1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.SingleFileGenerationTable1.RowName = {};
            app.SingleFileGenerationTable2 = uitable(app.ParametersPanel);
            app.SingleFileGenerationTable2.ColumnName = {'Column 1'};
            app.SingleFileGenerationTable2.RowName = {};
            app.SingleFileGenerationTable2.ColumnEditable = true;
            app.SingleFileGenerationTable2.CellEditCallback = createCallbackFcn(app, @SingleFileGenerationTable2CellEdit, true);
            app.SingleFileGenerationTable2.CellSelectionCallback = createCallbackFcn(app, @SingleFileGenerationTable2CellSelection, true);
            app.SaveSignalInfo = uicheckbox(app.ParametersPanel);
            app.SaveSignalInfo.ValueChangedFcn = createCallbackFcn(app, @SaveSignalInfoValueChanged, true);
            app.SaveSignalInfo.Text = 'Save Signal Info';
            app.LTEChannelTypeTbl = uitable(app.WaveformGenerationTab);
            app.LTEChannelTypeTbl.ColumnName = {'Column 1'; 'Column 2'};
            app.LTEChannelTypeTbl.RowName = {};
            app.WaveformPlayerTab = uitab(app.TabGroup);
            app.WaveformPlayerTab.Title = 'Waveform Player';
            app.WaveformPlayerTab.Units = 'pixels';
            app.DisplayPlayer = uibuttongroup(app.WaveformPlayerTab);
            app.DisplayPlayer.SelectionChangedFcn = createCallbackFcn(app, @DisplayPlayerSelectionChanged, true);
            app.DisplayPlayer.Title = 'Signal Display';
            app.FrequencyPlayer = uiradiobutton(app.DisplayPlayer);
            app.FrequencyPlayer.Text = 'Frequency';
            app.FrequencyPlayer.Value = true;
            app.TimePlayer = uiradiobutton(app.DisplayPlayer);
            app.TimePlayer.Text = 'Time';
            app.SpectrogramButton = uiradiobutton(app.DisplayPlayer);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.StopPlayer = uibutton(app.WaveformPlayerTab, 'push');
            app.StopPlayer.ButtonPushedFcn = createCallbackFcn(app, @StopPlayerButtonPushed, true);
            app.StopPlayer.Text = 'Stop';
            app.PlayPlayer = uibutton(app.WaveformPlayerTab, 'push');
            app.PlayPlayer.ButtonPushedFcn = createCallbackFcn(app, @PlayPlayerButtonPushed, true);
            app.PlayPlayer.Text = 'Play';
            app.SelectWaveformFolderPlayer = uibutton(app.WaveformPlayerTab, 'push');
            app.SelectWaveformFolderPlayer.ButtonPushedFcn = createCallbackFcn(app, @SelectWaveformFolderPlayerButtonPushed, true);
            app.SelectWaveformFolderPlayer.Text = 'Select Waveforms Data Folder';
            app.SelectRadarFile_2Label = uilabel(app.WaveformPlayerTab);
            app.SelectRadarFile_2Label.Text = 'Select File';
            app.SelectWaveformFilePlayer = uilistbox(app.WaveformPlayerTab);
            app.SelectWaveformFilePlayer.Items = {};
            app.SelectWaveformFilePlayer.ValueChangedFcn = createCallbackFcn(app, @SelectWaveformFilePlayerValueChanged, true);
            app.SelectWaveformFilePlayer.Value = {};
            app.WaveformInfoLabel = uilabel(app.WaveformPlayerTab);
            app.WaveformInfoLabel.Text = 'Waveform Info';
            app.WaveformInfo = uitextarea(app.WaveformPlayerTab);
            app.WaveformInfo.Editable = 'off';
            app.SetdataScaleGainEditFieldLabel = uilabel(app.WaveformPlayerTab);
            app.SetdataScaleGainEditFieldLabel.Text = 'Set data Scale/Gain';
            app.SetdataScaleGainEdt = uieditfield(app.WaveformPlayerTab, 'numeric');
            app.SetdataScaleGainEdt.ValueChangedFcn = createCallbackFcn(app, @SetdataScaleGainEdtValueChanged, true);
            app.SetdataScaleGainEdt.Limits = [0 10000000];
            app.SetdataScaleGainEdt.ValueDisplayFormat = '%e';
            app.SetdataScaleGainEdt.Value = 1e-07;
            app.DebugLbl = uilabel(app.ESCWaveGen);
            app.DebugLbl.Text = 'Label_DEBUG';
            app.LoadPreviewParameters = uibutton(app.ESCWaveGen, 'push');
            app.LoadPreviewParameters.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviewParametersButtonPushed, true);
            app.LoadPreviewParameters.Text = 'Load Preview>>Generation Parameters';
            app.ResetSpectrumAnalyzerandTimeSope = uibutton(app.ESCWaveGen, 'push');
            app.ResetSpectrumAnalyzerandTimeSope.ButtonPushedFcn = createCallbackFcn(app, @ResetSpectrumAnalyzerandTimeSopeButtonPushed, true);
            app.ResetSpectrumAnalyzerandTimeSope.Text = 'Reset Spectrum Analyzer and Time Sope';
            app.LogoImage = uiaxes(app.ESCWaveGen);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ESCWaveformGenerator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ESCWaveGen)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ESCWaveGen)
        end
    end
end